classdef GmshGridParsing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeshInfUIFigure    matlab.ui.Figure
        FilesMenu          matlab.ui.container.Menu
        OpenFileMenu       matlab.ui.container.Menu
        ViewMenu           matlab.ui.container.Menu
        ShowMeshMenu       matlab.ui.container.Menu
        MainPage           matlab.ui.container.Panel
        GridLayout3        matlab.ui.container.GridLayout
        GridLayout4        matlab.ui.container.GridLayout
        SelectedFile       matlab.ui.control.TextArea
        Label              matlab.ui.control.Label
        GridLayout2        matlab.ui.container.GridLayout
        GridLayout         matlab.ui.container.GridLayout
        OutputMeshInfBtn   matlab.ui.control.Button
        ShowMeshBtn        matlab.ui.control.Button
        ExtractMeshInfBtn  matlab.ui.control.Button
        OpenFileBtn        matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    properties (Access = public)
        file = "";
        path = "";
        FullFile = "";
        MeshInf = [];
        msgbox_style = struct('WindowStyle','modal', 'Interpreter', 'tex');
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: OpenFileBtn
        function OpenFileBtnPushed(app, event)
            [app.file,app.path] = uigetfile('*.msh');
            app.FullFile = fullfile(app.path, app.file);
            app.SelectedFile.Value = app.FullFile;
        end

        % Value changed function: SelectedFile
        function SelectedFileValueChanged(app, event)
            app.FullFile = string(app.SelectedFile.Value);
        end

        % Button pushed function: ExtractMeshInfBtn
        function ExtractMeshInfBtnPushed(app, event)
            if(app.FullFile ~= "")
                try 
                    fopen(app.FullFile);
                    app.MeshInf = MeshInfExtract(app.FullFile);
                    msgbox("\fontsize{13} 网格信息提取成功！",'提示信息','help', app.msgbox_style);
                catch
                    msgbox("\fontsize{13} 文件路径不正确或该文件不存在！",'错误信息','error', app.msgbox_style);
                end
            else
                msgbox("\fontsize{13} 请输入文件路径！",'警告信息','warn', app.msgbox_style);
            end 
        end

        % Menu selected function: OpenFileMenu
        function OpenFileMenuMenuSelected(app, event)
            [app.file,app.path] = uigetfile('*.msh');
            app.FullFile = fullfile(app.path, app.file);
            app.SelectedFile.Value = app.FullFile;
        end

        % Button pushed function: ShowMeshBtn
        function ShowMeshBtnPushed(app, event)
            try
                ShowMesh(app.MeshInf, app);
            catch
                msgbox("\fontsize{13} 网格显示失败！",'错误信息','error', app.msgbox_style);
            end  
        end

        % Button pushed function: OutputMeshInfBtn
        function OutputMeshInfBtnPushed(app, event)
            if(app.FullFile ~= "")
                try 
                    fopen(app.FullFile);
                    output_path = uigetdir();
                    MeshInf = app.MeshInf;
                    save(output_path+"\meshInf.mat", '-struct', 'MeshInf');
                    msgbox("\fontsize{13} 网格信息输出成功！",'提示信息','help', app.msgbox_style);
                catch
                    msgbox("\fontsize{13} 文件路径不正确或该文件不存在！",'错误信息','error', app.msgbox_style);
                end
            else
                msgbox("\fontsize{13} 请输入文件路径！",'警告信息','warn', app.msgbox_style);
            end 
        end

        % Menu selected function: ShowMeshMenu
        function ShowMeshMenuSelected(app, event)
            try
                ShowMesh(app.MeshInf, app);
            catch
                msgbox("\fontsize{13} 网格显示失败！",'错误信息','error', app.msgbox_style);
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeshInfUIFigure = uifigure('Visible', 'off');
            app.MeshInfUIFigure.Name = 'MeshInf';
            app.MeshInfUIFigure.Icon = 'logo.png';
            app.FilesMenu = uimenu(app.MeshInfUIFigure);
            app.FilesMenu.Text = '文件';
            app.OpenFileMenu = uimenu(app.FilesMenu);
            app.OpenFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuMenuSelected, true);
            app.OpenFileMenu.Text = '打开文件';
            app.ViewMenu = uimenu(app.MeshInfUIFigure);
            app.ViewMenu.Text = '查看';
            app.ShowMeshMenu = uimenu(app.ViewMenu);
            app.ShowMeshMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowMeshMenuSelected, true);
            app.ShowMeshMenu.Text = '显示网格';
            app.MainPage = uipanel(app.MeshInfUIFigure);
            app.GridLayout3 = uigridlayout(app.MainPage);
            app.GridLayout2 = uigridlayout(app.GridLayout3);
            app.UIAxes = uiaxes(app.GridLayout2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GridLayout = uigridlayout(app.GridLayout2);
            app.OpenFileBtn = uibutton(app.GridLayout, 'push');
            app.OpenFileBtn.ButtonPushedFcn = createCallbackFcn(app, @OpenFileBtnPushed, true);
            app.OpenFileBtn.Text = '打开文件';
            app.ExtractMeshInfBtn = uibutton(app.GridLayout, 'push');
            app.ExtractMeshInfBtn.ButtonPushedFcn = createCallbackFcn(app, @ExtractMeshInfBtnPushed, true);
            app.ExtractMeshInfBtn.Text = '提取网格信息';
            app.ShowMeshBtn = uibutton(app.GridLayout, 'push');
            app.ShowMeshBtn.ButtonPushedFcn = createCallbackFcn(app, @ShowMeshBtnPushed, true);
            app.ShowMeshBtn.Text = '显示网格';
            app.OutputMeshInfBtn = uibutton(app.GridLayout, 'push');
            app.OutputMeshInfBtn.ButtonPushedFcn = createCallbackFcn(app, @OutputMeshInfBtnPushed, true);
            app.OutputMeshInfBtn.Text = '输出网格信息';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '所选文件';
            app.SelectedFile = uitextarea(app.GridLayout4);
            app.SelectedFile.ValueChangedFcn = createCallbackFcn(app, @SelectedFileValueChanged, true);
            % Show the figure after all components are created
            app.MeshInfUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GmshGridParsing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeshInfUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeshInfUIFigure)
        end
    end
end